classdef GUICifrados_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uipanel1                    matlab.ui.container.Panel
        EditField_3                 matlab.ui.control.EditField
        LimpiarButton               matlab.ui.control.Button
        EditField_2                 matlab.ui.control.EditField
        HistogramaESINButton        matlab.ui.control.Button
        EditField                   matlab.ui.control.NumericEditField
        TipodeoperacionSwitch       matlab.ui.control.Switch
        TipodeoperacionSwitchLabel  matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        text3                       matlab.ui.control.Label
        popupmenu1                  matlab.ui.control.DropDown
        text2                       matlab.ui.control.Label
        pushbutton1                 matlab.ui.control.Button
        axes1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        alfabet = ["a" "b" "c" "d" "e" "f" "g" "h" "i" "j" "k" "l" "m" "n" "ñ" "o" "p" "q" "r" "s" "t" "u" "v" "w" "x" "y" "z"]; %ALFABETO PARA EL CIFRADO CESAR 
        CountAlpha = 0; % VARIABLE PARA ALMACENAR LA POSICION DEL ALFABETO
        Tp="";% VARIABLE PARA EL TEXTO PLANO
        temp = ""; %TEMPORAL PARA GUARDAR LOS CARACTERES DE LA CADENA INGRESADA POR EL USUARIO
        Tc = ""; % VARIABLE PARA EL TEXTO CIFRADO
        frecuencyTP = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];%Vector de frecuencias
        frecuencyTC = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];%Vector de frecuencias
        frecuency = [12.53,1.42,4.68,5.86,13.68,0.69,1.01,0.70,6.25,0.44,0.02,4.97,3.15,6.71,0.31,8.68,2.51,0.88,6.87,7.98,4.63,3.93,0.90,0.01,0.22,0.90,0.52];%Vector de frecuencias español
        frecuency2= [8.34,1.54,2.73,4.14,12.60,2.03,1.92,6.11,6.71,0.23,0.87,4.24,2.53,6.80,0,7.70,1.66,0.09,5.68,6.11,9.37,2.85,1.06,2.34,0.20,2.04,0.06];%Vector de frecuencias ingles
        KeyVi=[];%Vector vacio para la cantidad de veces que aparece una letra
        PosKey=1;%Contador
        FinalKey="";%Vector vacio para EL DICCIONARIO TEMPORAL
        Newdiccionario="";%Vector vacio para EL NUEVO DICCIONARIO DEL PLAYFAIR
        plaFairDic=["a" "b" "c" "d" "e" "f" "g" "h" "i" "k" "l" "m" "n" "o" "p" "q" "r" "s" "t" "u" "v" "w" "x" "y" "z"];
        neutro="";%Vector vacio para VALOR NEUTRO
        MatixPlay=[];%Vector vacio para la matriz de playfair y hill
        Normalize="";%Vector vacio para NORMAILIZACION
        M1="";% VARIABLE PARA EL TEXTO PLANO
        M2="";% VARIABLE PARA EL TEXTO PLANO
        C1="";% VARIABLE PARA EL TEXTO CIFRADO
        C2="";% VARIABLE PARA EL TEXTO CIFRADO
        frecuencyTPPlay = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];%Vector de frecuencias
        frecuencyTCPlay = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];%Vector de frecuencias
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUICifrados_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUICifrados (see VARARGIN)
            
            % Choose default command line output for GUICifrados
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

                val=app.popupmenu1.Value;
                switch(val)
                    case "Cesar"
                        if app.TipodeoperacionSwitch.Value == "Cifrado"
                                    for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTC(i)=0;%Igualando a cero los valores
                                    end
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", ""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                        app.TextArea.Value="No hay texto para cifrar"; % MENSAJE DE ADVERTENCIA
                                        return
                                    end % FIN DEL IF
                                    if Key == 1 || Key >= length(app.alfabet)%VALIDAR QUE LA LLAVE NO SEA IGUAL A 1 Y/O AL TAMAÑO DEL ALFABETO
                                        app.TextArea.Value="La llave no puede ser igual a 1 o al tamaño del diccionario"; % MENSAJE DE ADVERTENCIA
                                        return
                                    end % FIN DEL IF
                                    for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                        app.temp = strcat(app.temp, extract(app.Tp,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                        if find(app.alfabet == app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                            app.CountAlpha = find(app.alfabet == app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            newindex = app.CountAlpha + Key; % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MÁS LA LLAVE
                                            if newindex > length(app.alfabet) %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MAYOR AL TAMAÑO DEL ALFABETO SI ES ASI ENTRE AL IF
                                                newindex = mod(newindex, length(app.alfabet)); % SI EL NUEVO INDICE ES MAYOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                    newindex = length(app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                end % FIN DEL IF
                                            end % FIN DEL IF
                                                app.Tc = strcat(app.Tc, strrep(app.temp, app.alfabet(app.CountAlpha), app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                 app.frecuencyTP(newindex)= app.frecuencyTP(newindex)+1;%Aumentando el contador de la variable 
                                        end % FIN DEL IF
                                                app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                    end % FIN DEL FOR
                                        for i= 1:length(app.frecuencyTP)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTP(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTC(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                    c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                    legend(app.axes1,{'RAE';'Texto cifrado';'Ingles'});%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTP(i)=0;%Igualando a cero los valores
                                    end
                                    
                            else
                                        for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTC(i)=0;
                                        end
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", ""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                            app.TextArea.Value="No hay texto para cifrar"; % MENSAJE DE ADVERTENCIA
                                            return
                                        end % FIN DEL IF
                                        if Key == 1 || Key >= length(app.alfabet)%VALIDAR QUE LA LLAVE NO SEA IGUAL A 1 Y/O AL TAMAÑO DEL ALFABETO
                                            app.TextArea.Value="La llave no puede ser igual a 1 o al tamaño del diccionario"; % MENSAJE DE ADVERTENCIA
                                            return
                                        end % FIN DEL IF
                                            for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                                 app.temp = strcat( app.temp,  extract(app.Tc,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                                if find( app.alfabet ==  app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                                     app.CountAlpha = find( app.alfabet ==  app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                                    newindex =  app.CountAlpha - Key; % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MENOS LA LLAVE
                                                    if newindex < 1 %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MENOR A 1 SI ES ASI ENTRA AL IF
                                                        newindex = mod(newindex, length( app.alfabet)); % SI EL NUEVO INDICE ES MENOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                        if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                            newindex = length( app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                        end % FIN DEL IF
                                                    end % FIN DEL IF
                                                         app.Tp = strcat( app.Tp, strrep( app.temp,  app.alfabet( app.CountAlpha),  app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                         app.frecuencyTC(newindex)= app.frecuencyTC(newindex)+1;%Aumentando el contador de la variable 
                                                end % FIN DEL IF
                                                         app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                            end % FIN DEL FOR
                                            for i= 1:length(app.frecuencyTC)%Ciclo for para sacar las frecuencias de los caracteres
                                                    b = app.frecuencyTC(i);%Igualando b al valor actual de app.frecuencyTC(i)
                                                    c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                                    app.frecuencyTP(i)=c*100;%Multiplicando  app.frecuencyTP(i) por 100
                                            end
                                            app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                            alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                            c2=[app.frecuency;app.frecuencyTP;app.frecuency2];%VMatriz de las frecuencias
                                            bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                            legend(app.axes1,{'RAE';'Texto descifrado';'Ingles'});%Legendas para el histograma
                                            title(app.axes1,"Histograma de Frecuencias texto descifrado");%Titulo para el histograma
                                            for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                                app.frecuencyTP(i)=0;%Igualando a cero los valores
                                            end
                                            
                        end
                  case "Vigenere"
                            if app.TipodeoperacionSwitch.Value == "Cifrado" 
                                    for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTC(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                    app.KeyVi=[];%LIMPIAR VARIABLE
                                    app.PosKey = 1;%LIMPIAR VARIABLE
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""),"\r\n",""),"\n",""),"\r",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                       app.TextArea.Value="No hay texto para cifrar";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    if b==0%VALIDAR QUE LA LLAVE NO ESTA VACIO
                                       app.TextArea.Value="La llave no puede estar vacia";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                       app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        c = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                        app.KeyVi(i)=c;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                    end% FIN DEL FOR
                                    for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                        app.temp = strcat(app.temp, extract(app.Tp,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                        if find(app.alfabet == app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                            app.CountAlpha = find(app.alfabet == app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            newindex = app.CountAlpha + app.KeyVi(app.PosKey); % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MÁS LA LLAVE
                                            if newindex > length(app.alfabet) %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MAYOR AL TAMAÑO DEL ALFABETO SI ES ASI ENTRE AL IF
                                                newindex = mod(newindex, length(app.alfabet)); % SI EL NUEVO INDICE ES MAYOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                    newindex = length(app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                end % FIN DEL IF
                                            end % FIN DEL IF
                                                app.Tc = strcat(app.Tc, strrep(app.temp, app.alfabet(app.CountAlpha), app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                 app.frecuencyTP(newindex)= app.frecuencyTP(newindex)+1;%Aumentando el contador de la variable 
                                        end % FIN DEL IF
                                                app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                               if app.PosKey <= length(app.KeyVi)%VALIDACION PARA VER QUE EL CONTADOR SEA MENOR AL TAMAÑO DE LA LLAVE
                                                      app.PosKey= app.PosKey + 1;%Aumentando el contador de la variable 
                                                    if app.PosKey > length(app.KeyVi)%VALIDACION PARA QUE EL CONTADOR DE LA LLAVE  SE REINICIE
                                                            app.PosKey = 1;%Igualando a uno el contador   
                                                     end% FIN DEL IF
                                               end% FIN DEL IF
                                    end % FIN DEL FOR
                                        for i= 1:length(app.frecuencyTP)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTP(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTC(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                    c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                    legend(app.axes1,{'RAE';'Texto cifrado';'Ingles'});%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTP(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                            else
                                        for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTC(i)=0;%Igualando a cero los valores
                                        end% FIN DEL FOR
                                         app.KeyVi=[];%LIMPIAR VARIABLE
                                         app.PosKey = 1;%LIMPIAR VARIABLE
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                            app.TextArea.Value="No hay texto para descifrar";% MENSAJE DE ADVERTENCIA
                                            return
                                         end% FIN DEL IF
                                         b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                         if b==0%VALIDAR QUE LA LLAVE NO ESTA VACIO
                                             app.TextArea.Value="La llave no puede estar vacia";% MENSAJE DE ADVERTENCIA
                                             return
                                         end% FIN DEL IF
                                        for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                           app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                            c = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            app.KeyVi(i)=c;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                            app.temp="";%LIMPIAR VARIABLE
                                        end% FIN DEL FOR
                                            for index = 1:a % CICLO FOR PARA CIFRAR EL TEXTO PLANO CON CON UN RANGO DE 1 AL TAMAÑO DEL TEXTO PLANO
                                                 app.temp = strcat( app.temp,  extract(app.Tc,index)); % SUSTRACCION DE CARACTERES DEL TEXTO PLANO POR POSICION
                                                if find( app.alfabet ==  app.temp) % IF PARA VALIDAR QUE EL CARACTER ESTE EN EL ALFABETO
                                                     app.CountAlpha = find( app.alfabet ==  app.temp); % BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                                    newindex = app.CountAlpha - app.KeyVi(app.PosKey); % VARIABLE PARA ASIGNAR EL NUEVO INDICE SEGUN EL CIFRADO CESAR ES IGUAL A LA POSICION ACTUAL DEL CARACTER MENOS LA LLAVE
                                                    if newindex < 1 %IF PARA VALIDAR SI EL TAMAÑO DEL NUEVO INDICE ES MENOR A 1 SI ES ASI ENTRA AL IF
                                                        newindex = mod(newindex, length( app.alfabet)); % SI EL NUEVO INDICE ES MENOR SE APLICA EL MODULO DEL INDICE CON RESPECTO AL TAMAÑO DEL ALFABETO PARA OBTENER LA NUEVA POSICION
                                                        if newindex == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                            newindex = length( app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                        end % FIN DEL IF
                                                    end % FIN DEL IF
                                                         app.Tp = strcat( app.Tp, strrep( app.temp,  app.alfabet( app.CountAlpha),  app.alfabet(newindex))); %SE REMPLAZA EL CARACTER ANTERIOR CON EL NUEVO Y SE CONCATENA PARA OBTENER EL TEXTO CIFRADO
                                                         app.frecuencyTC(newindex)= app.frecuencyTC(newindex)+1;%Aumentando el contador de la variable 
                                                end % FIN DEL IF
                                                         app.temp = ""; % SE RESETEA LA VARIABLE TEMPORAL
                                                         if app.PosKey <= length(app.KeyVi)%VALIDACION PARA VER QUE EL CONTADOR SEA MENOR AL TAMAÑO DE LA LLAVE
                                                                app.PosKey= app.PosKey + 1;%Aumentando el contador de la variable 
                                                            if app.PosKey > length(app.KeyVi)%VALIDACION PARA QUE EL CONTADOR DE LA LLAVE  SE REINICIE
                                                                    app.PosKey = 1;%Igualando a uno el contador  
                                                            end% FIN DEL IF
                                                         end% FIN DEL IF
                                            end % FIN DEL FOR
                                            for i= 1:length(app.frecuencyTC)%Ciclo for para sacar las frecuencias de los caracteres
                                                    b = app.frecuencyTC(i);%Igualando b al valor actual de app.frecuencyTC(i)
                                                    c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                                    app.frecuencyTP(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                            end% FIN DEL FOR
                                            app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                            alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                            c2=[app.frecuency;app.frecuencyTP;app.frecuency2];%VMatriz de las frecuencias
                                            bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                            legend(app.axes1,{'RAE';'Texto descifrado';'Ingles'});%Legendas para el histograma
                                            title(app.axes1,"Histograma de Frecuencias texto descifrado");%Titulo para el histograma
                                            for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                                app.frecuencyTP(i)=0;%Igualando a cero los valores
                                            end% FIN DEL FOR
                            end% FIN DEL IF
                    case "Playfair"
                            if app.TipodeoperacionSwitch.Value == "Cifrado" 
                                    for i= 1:length(app.frecuencyTCPlay)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTCPlay(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                    app.Normalize="";%LIMPIAR VARIABLE
                                    app.FinalKey="";%LIMPIAR VARIABLE
                                    app.Newdiccionario="";%LIMPIAR VARIABLE
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.neutro = app.EditField_3.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.neutro =lower(app.neutro);%OBTENEMOS EL VALOR NEUTRO
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""),"\r\n",""),"\n",""),"\r",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp =strrep(strrep(app.Tp, "j", "i"),"ñ","n");% USO DEL METODO STRREP PARA REMPLAZAR J POR I Y REMPLAZAR Ñ POR N
                                    app.Tp=strrep(strrep(app.Tp,"‘",""),"’","");% ELIMINAR CARACTERES ESPECIALES
                                    app.Tp=strrep(strrep(strrep(strrep(app.Tp,"'",""),"@",""),"«",""),"»","");% ELIMINAR CARACTERES ESPECIALES
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                       app.TextArea.Value="No hay texto para cifrar";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    if b==0%VALIDAR QUE LA LLAVE NO ESTA VACIA
                                       app.TextArea.Value="La llave no puede estar vacia";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    count=1;% VARIABLE PARA CONTADOR
                                    count2=1;%VARIABLE PARA CONTADOR
                                    temp2="";% VARIABLE TEMPORAL
                                    j=a;% VARIABLE IGUALADA AL TAMAÑO DEL TEXTO
                                    if mod(a,2) == 1%VALIDAR QUE EL TAMAÑO DEL TEXTO ES IMPAR
                                        j= a+1; %J IGUAL AL LAGO DEL TEXTO MÀS UNO
                                        a = a+1; %A IGUAL A A MÁS UNO
                                        j= j/2; %DIVIDIR EL VALOR DE J ENTRE DOS PARA EL TAMAÑO FINAL
                                    else% SINO ES IMPAR
                                        j= j/2;%DIVIDIR EL VALOR DE J ENTRE DOS PARA EL TAMAÑO FINAL
                                    end% FIN DEL IF
                                    
                                    while count < a%WHILE PARA HACER LAS TUPLAS 
                                        app.temp = strcat(app.temp, extract(app.Tp,count));%GUARDAR EL PRIMER VALOR DEL TEXTO
                                         if count+1 > strlength(app.Tp) && count+1 == a%SI EL VALOR DE CONTADOR MÁS UNO ES MAYOR AL TAMAÑO DEL TEXTO E IGUAL A A
                                             temp2 =app.neutro;%AGREGAMOS EL VALOR NEUTRO
                                         else% SI NO 
                                             temp2 = strcat(temp2, extract(app.Tp,count+1));%AGREGAMOS EL VALOR DE LA ULTIMA POSICIÓN
                                         end% FIN DEL IF
                                          if temp2 == app.temp %SI TEMPORAL 1 Y TEMPORAL 2 SON IGUALES
                                             temp2= app.neutro;%TEMPORAL DOS IGUAL AL VALOR NEUTRO
                                             app.temp = strcat(app.temp,temp2);%Y CONCATENAMOS AL TEMPORAL 1
                                             else%SI NO
                                             app.temp = strcat(app.temp,temp2);%SOLO CONCATENAMOS
                                         end% FIN DEL IF
                                         if temp2 == app.neutro% SI VALOR DEL TEMPORAL 2 ES IGUAL AL VALOR NEUTRO 
                                             count= count+1;%AUMENTAMOS EL VALOR EN MÁS UNO
                                         else%SI NO
                                             count= count+2; % AUMENTAMOS EL VALOR EN MÁS DOS
                                         end% FIN DEL IF
                                         app.Normalize(count2)=app.temp;%AGREGAMOS EL TEMPORAL AL TEXTO NORMALIZADO
                                         app.temp="";%LIMPIAR VARIABLE
                                         temp2="";%LIMPIAR VARIABLE
                                         if count2 <j%VERIFICAMOS QUE EL CONTADOR SEA MENOR A J
                                             count2=count2+1;% AUMENTAMOS EN 1 EL CONTAODOR
                                         end% FIN DEL IF
                                    end% FIN DEL WHILE
                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        app.Newdiccionario(i)=app.temp;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                   end% FIN DEL FOR
                                    for i = 1:length(app.plaFairDic)%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.Newdiccionario(i+b)=app.plaFairDic(i);%AGREGAMOS EL ALFABETO AL NUEVO DICCIONARIO
                                    end% FIN DEL FOR
                                        app.FinalKey= unique(app.Newdiccionario,'stable');%QUITAMOS LOS VALORES REPETIDOS
                                        app.MatixPlay = reshape(app.FinalKey,5,5);   %CREAMOS LA MATRIZ DE 5X5
                                        app.MatixPlay = app.MatixPlay.';%TRANSPONEMOS LA MATRIZ
                                            z=length(app.Normalize);%IGUALAMOS A Z AL TAMAÑO DEL TEXTO NORMALIZADO

                                        for i=1:z%INCIO DEL FOR
                                            var1= app.Normalize(i);%IGUALAMOS VAR1 AL VALOR DE LA POSICION ACTUAL DEL TEXTO NORMALIZADO
                                            app.M1=strcat(app.M1,extract(var1,1));%M1 IGUAL A LA PRIMERA POSICION  DEL VALOR DE LA POSICION ACTUAL DEL TEXTO NORMALIZADO
                                            app.M2=strcat(app.M2,extract(var1,2));%M2 IGUAL A LA PRIMERA POSICION  DEL VALOR DE LA POSICION ACTUAL DEL TEXTO NORMALIZADO
                                            [numRows,numCols] = size(app.MatixPlay);% SACAMOS EL TAMAÑO DE LA MATRIZ DEL ALFABETO
                                            [row,col]= find(app.MatixPlay==app.M1);%BUSCAMOS LA POSICION DEL M1
                                            [row2,col2]= find(app.MatixPlay==app.M2); %BUSCAMOS LA POSICION DEL M2
                                            if row == row2%SI LAS FILAS SON IGUALES 
                                                if col + 1 > numCols%SI COL MÁS UNO ES MAYOR AL TAMAÑO DE LA COLUMNA DE LA MATRIZ DE CIFRADO
                                                    app.C1 =app.MatixPlay(row,mod(col + 1,numCols));%C1 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA COLUMNA
                                                    app.Tc = strcat( app.Tc,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                else%SI NO
                                                    app.C1 =app.MatixPlay(row,col + 1);%%C1 IGUAL A LA POSICION DE LA MATRIZ 
                                                    app.Tc = strcat( app.Tc,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                end% FIN DEL IF
                                                if col2 + 1 > numCols%SI COL2 MÁS UNO ES MAYOR AL TAMAÑO DE LA COLUMNA DE LA MATRIZ DE CIFRADO
                                                    app.C2 =app.MatixPlay(row,mod(col2 + 1,numCols));%C1 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA COLUMNA
                                                    app.Tc = strcat( app.Tc,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                else%SI NO
                                                    app.C2 =app.MatixPlay(row,col2 + 1);%C1 IGUAL A LA POSICION DE LA MATRIZ 
                                                    app.Tc = strcat( app.Tc,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                end% FIN DEL IF
                                            elseif col == col2%SI LAS COLUMNAS SON IGUALES 
                                                if row + 1 > numRows%SI ROW MÁS UNO ES MAYOR AL TAMAÑO DE LA FILA DE LA MATRIZ DE CIFRADO
                                                    app.C1 =app.MatixPlay(mod(row + 1,numRows),col);%C1 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA FILA
                                                    app.Tc = strcat( app.Tc,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                else%SI NO
                                                    app.C1 =app.MatixPlay(row + 1,col);%C1 IGUAL A LA POSICION DE LA MATRIZ 
                                                    app.Tc = strcat( app.Tc,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                end% FIN DEL IF
                                                if row2 + 1 > numRows%SI ROW2 MÁS UNO ES MAYOR AL TAMAÑO DE LA FILA DE LA MATRIZ DE CIFRADO
                                                    app.C2 =app.MatixPlay(mod(row2 + 1,numRows),col2);%C2 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA FILA
                                                    app.Tc = strcat( app.Tc,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                else%SI NO
                                                    app.C2 =app.MatixPlay(row2 + 1,col2);%C2 IGUAL A LA POSICION DE LA MATRIZ 
                                                    app.Tc = strcat( app.Tc,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                end% FIN DEL IF
                                            elseif col~=col2 && row~=row2%SI COL Y COL2 Y ROW Y ROW2 SON DIFERENTES 
                                                  incol=col2;%INVERSA DE LA COL IGUAL A COL 2
                                                  incol2=col;%INVERSA DE LA COL2 IGUAL A COL 
                                                  app.C1 =app.MatixPlay(row,incol);%C1 IGUAL A LA POSICION DE LA MATRIZ 
                                                  app.C2 =app.MatixPlay(row2,incol2);%C2 IGUAL A LA POSICION DE LA MATRIZ 
                                                  app.Tc = strcat( app.Tc,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                  app.Tc = strcat( app.Tc,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                            end% FIN DEL IF
                                            var1="";%LIMPIAR VARIABLE
                                            app.M1="";%LIMPIAR VARIABLE
                                            app.M2="";%LIMPIAR VARIABLE
                                            app.C1="";%LIMPIAR VARIABLE
                                            app.C2="";%LIMPIAR VARIABLE
                                        end% FIN DEL IF
                                        for i=1:strlength(app.Tc)%INICIO DEL FOR
                                            app.temp=strcat(app.temp,extract(app.Tc,i));%EXTRAEMOS EL VALOR DE TEXTO CIFRADO EN LA POSICION i
                                            index= find(app.MatixPlay == app.temp);%LA BUSCAMOS EN LA METRI DEL DICCIONARIO
                                            app.frecuencyTPPlay(index)= app.frecuencyTPPlay(index) + 1;%GUARDAMOS LA FRECUENDIA DE LAS APARICIONES DEL VALOR TEMP
                                            app.temp="";%LIMPIAR VARIABLE
                                        end%FIN DEL FOR
                                        for i= 1:length(app.frecuencyTPPlay)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTPPlay(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTCPlay(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical(app.FinalKey);%Vector del alfabeto para el histograma
                                    %c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,app.frecuencyTCPlay);%Funcion para crear el histograma
                                    legend(app.axes1,'Texto cifrado');%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTPPlay)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTPPlay(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                     
                            else
                                        for i= 1:length(app.frecuencyTCPlay)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTCPlay(i)=0;%Igualando a cero los valores
                                        end% FIN DEL FOR
                                         app.Normalize="";
                                         app.FinalKey="";
                                         app.Newdiccionario="";
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.neutro = app.EditField_3.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.neutro =lower(app.neutro);
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc =strrep(strrep(app.Tc, "j", "i"),"ñ","n");% USO DEL METODO STRREP PARA REMPLAZAR J POR I Y REMPLAZAR Ñ POR N
                                         app.Tc=strrep(strrep(app.Tc,"‘",""),"’","");% ELIMINAR CARACTERES ESPECIALES
                                         app.Tc=strrep(strrep(strrep(strrep(app.Tc,"'",""),"@",""),"«",""),"»","");% ELIMINAR CARACTERES ESPECIALES
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         if a==0%VERIFICAMOS QUE EL TEXTO NO ESTE VACIO
                                            app.TextArea.Value="No hay texto para descifrar";% MENSAJE DE ADVERTENCIA
                                            return
                                         end%FIN DEL IF
                                         b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                         if b==0%VERIFICAMOS QUE LA LLAVE NO ESTE VACIA
                                             app.TextArea.Value="La llave no puede estar vacia";% MENSAJE DE ADVERTENCIA
                                             return
                                         end%FIN DEL IF
                                        count=1;% VARIABLE PARA CONTADOR
                                        count2=1;%VARIABLE PARA CONTADOR
                                        temp2="";% VARIABLE TEMPORAL
                                        j=a;% VARIABLE IGUALADA AL TAMAÑO DEL TEXTO
                                        if mod(a,2) == 1%VALIDAR QUE EL TAMAÑO DEL TEXTO ES IMPAR
                                        j= a+1; %J IGUAL AL LAGO DEL TEXTO MÀS UNO
                                        a = a+1; %A IGUAL A A MÁS UNO
                                        j= j/2; %DIVIDIR EL VALOR DE J ENTRE DOS PARA EL TAMAÑO FINAL
                                        else% SINO ES IMPAR
                                            j= j/2;%DIVIDIR EL VALOR DE J ENTRE DOS PARA EL TAMAÑO FINAL
                                        end% FIN DEL IF
                                    while count < a%INICIO DEL WHILE 
                                        app.temp = strcat(app.temp, extract(app.Tc,count));%EXTRAEMOS EL CARACTER DEL TEXTO CIFRADO 
                                         if count+1 > a%SI EL CONTADOR MÁS UNO ES MAYOR A
                                             %NO HACER NADA
                                         else
                                             temp2 = strcat(temp2, extract(app.Tc,count+1));%EXTRAEMOS EL CARACTER DEL TEXTO CIFRADO EN LA POSICION MÁS UNO
                                         end
                                             app.temp = strcat(app.temp,temp2);%CONCATENAMOS LOS TEMPORALES
                                             count= count+2;%AUMENTAMOS EL CONTADOR EN 2
                                         app.Normalize(count2)=app.temp;%CONCATENAMOS EL TEMPORAL AL TEXTO NORMALIZADO
                                         app.temp="";%LIMPIAR VARIABLE
                                         temp2="";%LIMPIAR VARIABLE
                                         if count2 <j%VERIFICAMOS QUE EL CONTADOR SEA MENOR A J
                                             count2=count2+1;% AUMENTAMOS EN 1 EL CONTAODOR
                                         end% FIN DEL IF
                                    end%FIN DEL WHILE

                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        app.Newdiccionario(i)=app.temp;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                   end% FIN DEL FOR
                                    for i = 1:length(app.plaFairDic)%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.Newdiccionario(i+b)=app.plaFairDic(i);%AGREGAMOS LOS VALORES AL NUEVO DICCIONARIO
                                    end% FIN DEL FOR
                                        app.FinalKey= unique(app.Newdiccionario,'stable');%QUITAMOS LOS VALORES REPETIDOS
                                        app.MatixPlay = reshape(app.FinalKey,5,5); %CREAMOS LA MATRIZ DE 5X5
                                        app.MatixPlay = app.MatixPlay.';%TRANSPONEMOS LA MATRIZ
                                        

                                        for i=1:length(app.Normalize)%INICIO DE FOR
                                            var1= app.Normalize(i);%IGUALAMOS VAR1 AL VALOR DE LA POSICION ACTUAL DEL TEXTO NORMALIZADO
                                            app.M1=strcat(app.M1,extract(var1,1));%M1 IGUAL A LA PRIMERA POSICION  DEL VALOR DE LA POSICION ACTUAL DEL TEXTO NORMALIZADO
                                            app.M2=strcat(app.M2,extract(var1,2));%M2 IGUAL A LA PRIMERA POSICION  DEL VALOR DE LA POSICION ACTUAL DEL TEXTO NORMALIZADO
                                            [numRows,numCols] = size(app.MatixPlay);% SACAMOS EL TAMAÑO DE LA MATRIZ DEL ALFABETO
                                            [row,col]= find(app.MatixPlay==app.M1);%BUSCAMOS LA POSICION DEL M1
                                            [row2,col2]= find(app.MatixPlay==app.M2); %BUSCAMOS LA POSICION DEL M2
                                            if row == row2%SI LAS FILAS SON IGUALES 
                                                if col - 1 < 1%SI COL MENOS UNO ES MENOR A 1
                                                    app.C1 =app.MatixPlay(row,numCols);%C1 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA COLUMNA
                                                    app.Tp = strcat( app.Tp,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                else%SI NO
                                                    app.C1 =app.MatixPlay(row,col - 1);%C1 IGUAL A LA POSICION DE LA MATRIZ
                                                    app.Tp = strcat( app.Tp,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                end% FIN DEL IF
                                                if col2 - 1 < 1%SI COL2  MENOS UNO ES MENOR A 1
                                                    app.C2 =app.MatixPlay(row,numCols);%C2 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA COLUMNA
                                                    app.Tp = strcat( app.Tp,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                else%SI NO
                                                    app.C2 =app.MatixPlay(row,col2 - 1);%C2 IGUAL A LA POSICION DE LA MATRIZ 
                                                    app.Tp = strcat( app.Tp,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                end% FIN DEL IF
                                            elseif col == col2%SI LAS COLUMNAS SON IGUALES 
                                                if row - 1 < 1%SI ROW  MENOS UNO ES MENOR A 1
                                                    app.C1 =app.MatixPlay(numRows,col);%C1 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA FILA
                                                    app.Tp = strcat( app.Tp,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                else%SI NO
                                                    app.C1 =app.MatixPlay(row - 1,col);%C1 IGUAL A LA POSICION DE LA MATRIZ
                                                    app.Tp = strcat( app.Tp,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                end% FIN DEL IF
                                                if row2 - 1 < 1%SI ROW2  MENOS UNO ES MENOR A 1
                                                    app.C2 =app.MatixPlay(numRows,col2);%C2 IGUAL A LA POSICION DE LA MATRIZ SACANDO EL MODULO DE LA POSICION DE LA FILA
                                                    app.Tp = strcat( app.Tp,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                else%SI NO
                                                    app.C2 =app.MatixPlay(row2 - 1,col2);%C2 IGUAL A LA POSICION DE LA MATRIZ
                                                    app.Tp = strcat( app.Tp,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                                end% FIN DEL IF
                                            elseif col ~= col2 && row ~= row2%SI COL Y COL2 Y ROW Y ROW2 SON DIFERENTES 
                                                  incol=col2;%INVERSA DE LA COL IGUAL A COL 2
                                                  incol2=col;%INVERSA DE LA COL2 IGUAL A COL
                                                  app.C1 =app.MatixPlay(row,incol);%INVERSA DE LA COL2 IGUAL A COL
                                                  app.C2 =app.MatixPlay(row2,incol2);%C2 IGUAL A LA POSICION DE LA MATRIZ 
                                                  app.Tp = strcat( app.Tp,app.C1);%TEXTO CIFRADO CONCATENADO A C1
                                                  app.Tp = strcat( app.Tp,app.C2);%TEXTO CIFRADO CONCATENADO A C2
                                            end% FIN DEL IF
                                            var1="";%LIMPIAR VARIABLE
                                            app.M1="";%LIMPIAR VARIABLE
                                            app.M2="";%LIMPIAR VARIABLE
                                            app.C1="";%LIMPIAR VARIABLE
                                            app.C2="";%LIMPIAR VARIABLE
                                        end% FIN DEL FOR
                                        for i=1:strlength(app.Tp)%INICIO DEL FOR
                                            app.temp=strcat(app.temp,extract(app.Tp,i));%EXTRAEMOS EL VALOR DE TEXTO CIFRADO EN LA POSICION i
                                            index= find(app.MatixPlay == app.temp);%LA BUSCAMOS EN LA METRI DEL DICCIONARIO
                                            app.frecuencyTCPlay(index)= app.frecuencyTPClay(index) + 1;%GUARDAMOS LA FRECUENDIA DE LAS APARICIONES DEL VALOR TEMP
                                            app.temp="";%LIMPIAR VARIABLE
                                        end%FIN DEL FOR
%                                         for i=1:strlength(app.Tp)
%                                             app.temp=strcat(app.temp,extract(app.Tp,i));
%                                             index= find(app.MatixPlay == app.temp);
%                                             app.frecuencyTCPlay(index)= app.frecuencyTCPlay(index) + 1;
%                                             app.temp="";
%                                         end
                                        for i= 1:length(app.frecuencyTCPlay)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTCPlay(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTPPlay(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                    app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical(app.FinalKey);%Vector del alfabeto para el histograma
                                    %c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,app.frecuencyTPPlay);%Funcion para crear el histograma
                                    legend(app.axes1,'Texto cifrado');%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTPPlay)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTPPlay(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                            end% FIN DEL IF
                    case "Hill"
                            if app.TipodeoperacionSwitch.Value == "Cifrado" 
                                    for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTC(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                    app.KeyVi=[];%LIMPIAR VARIABLE
                                    app.Tc = ""; %LIMPIAR VARIABLE
                                    app.Tp= app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                    Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.neutro = app.EditField_3.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                    app.neutro =lower(app.neutro);
                                    app.Tp = lower(app.Tp); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    app.Tp = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tp, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""),"\r\n",""),"\n",""),"\r",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    app.Tp=strrep(strrep(app.Tp,"‘",""),"’","");%ELIMINAR CARACTERES ESPECIALES
                                    app.Tc =strrep(app.Tc,"ñ","n");
                                    app.Tp=strrep(strrep(strrep(strrep(app.Tp,"'",""),"@",""),"«",""),"»","");%ELIMINAR CARACTERES ESPECIALES
                                    app.Tp = strtrim(app.Tp); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                    Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                    Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                    a = strlength(app.Tp); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                    if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                       app.TextArea.Value="No hay texto para cifrar";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    if b==0%VALIDAR QUE LA LLAVE NO ESTA VACIA
                                       app.TextArea.Value="La llave no puede estar vacia";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    
                                    if b > 9%VALIDAR SI LA LLAVE ES MAYOR A NUEVE
                                        app.TextArea.Value="La llave no puede ser mayor a 9 caracteres";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    if b < 9%VALIDAR SI LA LLAVE ES MENOR A NUEVE
                                        sizeKey = 9 - b;%EL VALOR DEL TAMAÑO DE LA LLAVE MENOS 9
                                        count=1;%CONTADOR IGUAL A 1 
                                            while sizeKey ~=0%INICIO DEL WHILE
                                                app.temp = strcat(app.temp, app.alfabet(count));%EXTRAEMOS UN CARACTER DEL DICCIONARIO
                                                Key = strcat(Key, app.temp);%LO CONCATENAMOS A LA LLAVE
                                                sizeKey = sizeKey -1;% LE RESTAMOS EL VALOR DEL CONTADOR UNO
                                                count = count+1;%CONTADOR MÁS UNO
                                                app.temp="";%LIMPIAMOS VARIABLE
                                            end% FIN DEL WHILE
                                    end% FIN DEL IF
                                    res=1;
                                     while res ~=0%INICIO DEL WHILE
                                            res=mod(a,3);%SACAMOS MODULO DEL TAMAÑO DEL TEXTO 
                                            if res == 0%SI ES IGUAL A 0 CONTINUAMOS 
                                                continue;%ROMPEMOS EL CICLO
                                            else%SINO
                                                app.Tp = strcat(app.Tp , "x");%AGREGAMOS UN VALOR NEUTRO
                                                a=strlength(app.Tp); %VOLVEMOS A SACAR EL TAMAÑO DEL TEXTO
                                            end% FIN DEL IF
                                     end% FIN DEL WHILE
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        c = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                        app.KeyVi(i)=c;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                    end% FIN DEL FOR
                                        app.MatixPlay = reshape(app.KeyVi,3,3);  %CREAMOS MATRIZ DE 3X3 
                                        app.MatixPlay = app.MatixPlay.';%TRANSPONEMOS LA MATRIZ
                                        app.MatixPlay = [35 53 12;12 21 5;2 4 1];
%                                         app.MatixPlay = inv(app.MatixPlay);
%                                         for i=1:3
%                                             for j=1:3
%                                                 val=mod(app.MatixPlay(i,j),length(app.alfabet));
%                                                     val=floor(val);
%                                                 if val == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
%                                                    val = 21; % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
%                                                 end % FIN DEL IF
%                                                 app.MatixPlay(i,j)=val;
%                                             end
%                                         end
                                        I=1;
                                    while I <= a%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        A=0;%TEMPORALES
                                        B=0;%TEMPORALES
                                        D=0;%TEMPORALES
                                        count=I;%CONTADOR
                                            app.temp = strcat(app.temp, extract(app.Tp,count)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                            A = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            if count + 1 < a && count + 2 <=a%SI CONTADOR MÁS UNO Y CONTADOR MÁS 2 SON MENORES AL TAMAÑO DEL TEXTO
                                                app.temp="";%LIMPIAMOS VARIABLES
                                                 app.temp = strcat(app.temp, extract(app.Tp,count + 1)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                                 B = find(app.alfabet == app.temp);%IGUALAMOS B AL VALOR DEL ALFABETO
                                                 app.temp="";%LIMPIAMOS VARIABLES
                                                 app.temp = strcat(app.temp, extract(app.Tp,count + 2)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                                 D = find(app.alfabet == app.temp);%IGUALAMOS D AL VALOR DEL ALFABETO
                                            else%SINO
                                                continue;
                                            end% FIN DEL IF
                                            H = [A B D];%CREAMOS UN VECTO DE 1X3 
                                            H= H.';%TRSNPONEMOS EL VECTOR
                                            res = app.MatixPlay * H;%MULTIPLICAMOS MATRICES
                                            res=res.';%TRANSPONEMOS LA MATRIZ RESULTANTE
                                            for i = 1:length(res)%INICIO DEL FOR
                                                val= mod(res(i),length(app.alfabet));%SACAMOS MODULO DE CADA VALOR DE LA MATRIZ RESULTANTE
                                                if val == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                    val = length(app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                end % FIN DEL IF
                                                app.frecuencyTP(val)= app.frecuencyTP(val)+1;%Aumentando el contador de la variable 
                                                 aux =app.alfabet(val);%GUARDAMOS EL MODULO 
                                                app.Tc=strcat(app.Tc,aux);%CONCATENAMOS AL TEXTO CIFRADO
                                            end%FIN DEL FOR
                                            app.temp="";%LIMPIAR VARIABLE
                                        I= I+3;
                                    end% FIN DEL while

                                      for i= 1:length(app.frecuencyTP)%Ciclo for para sacar las frecuencias de los caracteres
                                            b = app.frecuencyTP(i);%Igualando b al valor actual de app.frecuencyTP(i)
                                            c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                            app.frecuencyTC(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                       end% FIN DEL FOR
                                    app.TextArea.Value=app.Tc;% SE IMPRIME EL TEXTO CIFRADO
                                    app.Tp = ""; %LIMPIAR VARIABLE
                                    alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                    c2=[app.frecuency;app.frecuencyTC;app.frecuency2];%VMatriz de las frecuencias
                                    bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                    legend(app.axes1,{'RAE';'Texto cifrado';'Ingles'});%Legendas para el histograma
                                    title(app.axes1,"Histograma de Frecuencias texto cifrado");%Titulo para el histograma
                                    for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                        app.frecuencyTP(i)=0;%Igualando a cero los valores
                                    end% FIN DEL FOR
                                     
                            else
                                        for i= 1:length(app.frecuencyTC)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTC(i)=0;%Igualando a cero los valores
                                        end% FIN DEL FOR
                                         app.KeyVi=[];%LIMPIAR VARIABLE
                                         app.Tp = ""; %LIMPIAR VARIABLE
                                         app.Tc = app.TextArea.Value; % CONSULTA AL USUARIO PARA LA OBTENER EL TEXTO PLANO
                                         Key = app.EditField_2.Value; % CONSULTA AL USUARIO PARA EL TAMAÑO DE LA LLAVE
                                         app.Tc = lower( app.Tc); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep( app.Tc, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         app.Tc = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(app.Tc, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         app.Tc =strrep(app.Tc,"ñ","n");
                                         app.Tc=strrep(strrep(app.Tc,"‘",""),"’","");
                                         app.Tc=strrep(strrep(strrep(strrep(app.Tc,"'",""),"@",""),"«",""),"»","");
                                         app.Tc = strtrim( app.Tc); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         Key = lower(Key); % CONVERSION DE LA CADENA EN MINUSCULAS
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(Key, "á", "a"), "é", "e"), "í", "i"), "ó", "o"), "ú", "u"),"ü","u"); % USO DEL METODO STRREP PARA REMPLAZAR LAS VOCALES CON ACENTO
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "0", "cero"), "1", "uno"), "2", "dos"), "3", "tres"), "4", "cuatro"), "5", "cinco"), "6", "seis"), "7", "siete"), "8", "ocho"), "9", "nueve"); % USO DEL METODO STRREP PARA REMPLAZAR NUMERO PARA SU CONTRA PARTE ALFABETICA
                                         Key = strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(strrep(Key, "!", ""), "¡", ""), "?", ""), "¿", ""), "(", ""), ")", ""), ";", ""), ",", ""), ".", ""), ":", ""), "-", ""), "_", ""), "<", ""), ">", ""), "..", ""), "...", ""), "/", ""), "}", ""), "{", ""), "[", ""), "]", ""), "+", ""), "*", ""), "$", ""), "%", ""), "&", ""), "=", ""), "|", "")," ",""); % USO DEL METODO STRREP PARA REMPLAZAR SIGNOS
                                         Key = strtrim(Key); %LIMPIAR ESPACIOS AL INICIO Y AL FINAL
                                         a =  strlength(app.Tc); % VARIABLE PARA ALMACENAR EL TAMAÑO DEL TEXTO PLANO
                                         
                                    if a==0%VALIDAR QUE EL TEXTO NO ESTA VACIO
                                       app.TextArea.Value="No hay texto para cifrar";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    if b==0%VALIDAR QUE LA LLAVE NO ESTA VACIA
                                       app.TextArea.Value="La llave no puede estar vacia";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    
                                    if b > 9%VALIDAR SI LA LLAVE ES MAYOR A NUEVE
                                        app.TextArea.Value="La llave no puede ser mayor a 9 caracteres";% MENSAJE DE ADVERTENCIA
                                       return
                                    end% FIN DEL IF
                                    if b < 9%VALIDAR SI LA LLAVE ES MENOR A NUEVE
                                        sizeKey = 9 - b;%EL VALOR DEL TAMAÑO DE LA LLAVE MENOS 9
                                        count=1;%CONTADOR IGUAL A 1 
                                            while sizeKey ~=0%INICIO DEL WHILE
                                                app.temp = strcat(app.temp, app.alfabet(count));%EXTRAEMOS UN CARACTER DEL DICCIONARIO
                                                Key = strcat(Key, app.temp);%LO CONCATENAMOS A LA LLAVE
                                                sizeKey = sizeKey -1;% LE RESTAMOS EL VALOR DEL CONTADOR UNO
                                                count = count+1;%CONTADOR MÁS UNO
                                                app.temp="";%LIMPIAMOS VARIABLE
                                            end% FIN DEL WHILE
                                    end% FIN DEL IF

                                    b= strlength(Key);%VARIABLE PARA ALMACENAR EL TAMAÑO DE LA LLAVE
                                    for i = 1:b%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                       app.temp = strcat(app.temp, extract(Key,i)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                        c = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                        app.KeyVi(i)=c;%SE AGREGA AL VECTOR LA POSCISION DEL CARACTER
                                        app.temp="";%LIMPIAR VARIABLE
                                    end% FIN DEL FOR
                                        app.MatixPlay = reshape(app.KeyVi,3,3);  %CREAMOS MATRIZ DE 3X3 
                                        app.MatixPlay = app.MatixPlay.';%TRANSPONEMOS LA MATRIZ
                                        app.MatixPlay = [35 53 12;12 21 5;2 4 1];
                                            determinant=det(app.MatixPlay);
                                            if(mod(determinant,2)==0)
                                                  app.TextArea.Value="El determinante de la llave debe ser impar";
                                                  return;
                                            end
                                           inverse=[0 0 0;0 0 0; 0 0 0];
                                             for i=0:2
                                                for j=0:2
                                                    p1=app.MatixPlay(mod((j+1),3)+1,mod((i+1),3)+1);
                                                    p2=app.MatixPlay(mod((j+2),3)+1,mod((i+2),3)+1);
                                                    p3=app.MatixPlay(mod((j+1),3)+1,mod((i+2),3)+1);
                                                    p4=app.MatixPlay(mod((j+2),3)+1,mod((i+1),3)+1);
                                                    inverse(i+1,j+1)=((p1 *p2) - (p3 * p4)) /determinant;
                                                end
                                            end
                                            app.MatixPlay=mod(inverse,length(app.alfabet));
                                            
                                        I=1;
                                    while I <= a%FOR PARA OBTENER LAS POCISIONES EN EL ALFABETO DE LA LLAVE
                                        A=0;%TEMPORALES
                                        B=0;%TEMPORALES
                                        D=0;%TEMPORALES
                                        count=I;%CONTADOR
                                            app.temp = strcat(app.temp, extract(app.Tc,count)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                            A = find(app.alfabet == app.temp); %BUSQUEDA DEL CARACTER EN EL ALFABETO PARA GUARDAR SU INDICE
                                            if count + 1 < a && count + 2 <=a%SI CONTADOR MÁS UNO Y CONTADOR MÁS 2 SON MENORES AL TAMAÑO DEL TEXTO
                                                app.temp="";%LIMPIAMOS VARIABLES
                                                 app.temp = strcat(app.temp, extract(app.Tc,count + 1)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                                 B = find(app.alfabet == app.temp);%IGUALAMOS B AL VALOR DEL ALFABETO
                                                 app.temp="";%LIMPIAMOS VARIABLES
                                                 app.temp = strcat(app.temp, extract(app.Tc,count + 2)); % SUSTRACCION DE CARACTERES DE LA LLAVE POR POSICION
                                                 D = find(app.alfabet == app.temp);%IGUALAMOS D AL VALOR DEL ALFABETO
                                            else%SINO
                                                continue;
                                            end% FIN DEL IF
                                            H = [A B D];%CREAMOS UN VECTODE 1X3 
                                            H= H.';%TRSNPONEMOS EL VECTOR
                                            res = app.MatixPlay * H;%MULTIPLICAMOS MATRICES
                                            res=res.';%TRANSPONEMOS LA MATRIZ RESULTANTE
                                            for i = 1:length(res)%INICIO DEL FOR
                                                val= mod(res(i),length(app.alfabet));%SACAMOS MODULO DE CADA VALOR DE LA MATRIZ RESULTANTE
                                                if val == 0 % SI EL NUEVO INDICE ES CERO AIGNA EL TAMAÑO DEL ALFABETO
                                                    val = length(app.alfabet); % SE LE ASIGNA AL NUEVO INDICE EL TAMAÑO DEL ALFABETO
                                                end % FIN DEL IF
                                                val= int32(val);
                                                app.frecuencyTC(val)= app.frecuencyTC(val)+1;%Aumentando el contador de la variable 
                                                 aux =app.alfabet(val);%GUARDAMOS EL MODULO 
                                                app.Tp=strcat(app.Tp,aux);%CONCATENAMOS AL TEXTO CIFRADO
                                            end%FIN DEL FOR
                                            app.temp="";%LIMPIAR VARIABLE
                                        I= I+3;
                                    end% FIN DEL while
                                        for i= 1:length(app.frecuencyTC)%Ciclo for para sacar las frecuencias de los caracteres
                                                    b = app.frecuencyTC(i);%Igualando b al valor actual de app.frecuencyTC(i)
                                                    c= b/a;%Dividiendo el valor actual entre el tamaño del texto 
                                                    app.frecuencyTP(i)=c*100;%Multiplicando  app.frecuencyTC(i) por 100
                                        end% FIN DEL FOR
                                        app.TextArea.Value=app.Tp;% SE IMPRIME EL TEXTO CIFRADO
                                        alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                                        c2=[app.frecuency;app.frecuencyTP;app.frecuency2];%VMatriz de las frecuencias
                                        bar(app.axes1,alfabet2,c2);%Funcion para crear el histograma
                                        legend(app.axes1,{'RAE';'Texto descifrado';'Ingles'});%Legendas para el histograma
                                        title(app.axes1,"Histograma de Frecuencias texto descifrado");%Titulo para el histograma
                                        for i= 1:length(app.frecuencyTP)%Ciclo for par limpiar el vector de frecuencia
                                            app.frecuencyTP(i)=0;%Igualando a cero los valores
                                        end% FIN DEL FOR
                            end% FIN DEL IF
                end% FIN DEL SWITCH
        end

        % Value changed function: popupmenu1
        function popupmenu1ValueChanged(app, event)
            if app.popupmenu1.Value == "Cesar"%VALIDACION PARA VER SI ESTA SELECCIONADO EL CIFRADO CESAR 
               app.EditField_3.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField_2.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField.Visible="on";%SI  ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA HABILITADO
            elseif app.popupmenu1.Value == "Vigenere"
                app.EditField_3.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField_2.Visible="on";%SI NO ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA HABILITADO
               app.EditField.Visible="off";%SI NO ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA DESHABILITADO
            elseif app.popupmenu1.Value == "Hill"
                app.EditField_3.Visible="off";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
               app.EditField_2.Visible="on";%SI NO ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA HABILITADO
               app.EditField.Visible="off";%SI NO ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA DESHABILITADO
            else %%Playfair
                app.EditField_3.Visible="on";%SI  ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA DESHABILITADO
                 app.EditField_2.Visible="on";%SI NO ES EL CIFRADO CESAR EL TEXTBOX DE TEXTO ESTA HABILITADO
                 app.EditField.Visible="off";%SI NO ES EL CIFRADO CESAR EL TEXTBOX NUMERICO ESTA DESHABILITADO
            end% FIN DEL IF
        end

        % Button pushed function: HistogramaESINButton
        function HistogramaESINButtonPushed(app, event)
                
                alfabet2 = categorical({'a' 'b' 'c' 'd' 'e' 'f' 'g' 'h' 'i' 'j' 'k' 'l' 'm' 'n' 'ñ' 'o' 'p' 'q' 'r' 's' 't' 'u' 'v' 'w' 'x' 'y' 'z'});%Vector del alfabeto para el histograma
                c1=[app.frecuency;app.frecuency2];%VMatriz de las frecuencias
                bar(app.axes1,alfabet2,c1);%Funcion para crear el histograma
                legend(app.axes1,{'Español';"Ingles"});%Legendas para el histograma
                title(app.axes1,"Histograma de Frecuencias Español e Ingles");%Titulo para el histograma
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.TextArea.Value="";
            cla(app.axes1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUICifrados';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Cifrados Clasicos';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Ejecutar';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Insertar Texto';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Cesar', 'Vigenere', 'Playfair', 'Hill'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1ValueChanged, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Cesar';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Insertar llave';
            app.TextArea = uitextarea(app.uipanel1);
            app.TipodeoperacionSwitchLabel = uilabel(app.uipanel1);
            app.TipodeoperacionSwitchLabel.Text = 'Tipo de operacion';
            app.TipodeoperacionSwitch = uiswitch(app.uipanel1, 'slider');
            app.TipodeoperacionSwitch.Items = {'Cifrado', 'Descifrado'};
            app.TipodeoperacionSwitch.Value = 'Cifrado';
            app.EditField = uieditfield(app.uipanel1, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.Value = 2;
            app.HistogramaESINButton = uibutton(app.uipanel1, 'push');
            app.HistogramaESINButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaESINButtonPushed, true);
            app.HistogramaESINButton.Text = 'Histograma ES/IN';
            app.EditField_2 = uieditfield(app.uipanel1, 'text');
            app.EditField_2.Visible = 'off';
            app.EditField_2.Placeholder = 'Ingresa tu llave';
            app.LimpiarButton = uibutton(app.uipanel1, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar ';
            app.EditField_3 = uieditfield(app.uipanel1, 'text');
            app.EditField_3.Visible = 'off';
            app.EditField_3.Placeholder = 'Valor neutro';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUICifrados_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUICifrados_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end